classdef ButtonDown_Example_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        OutputEditField       matlab.ui.control.NumericEditField
        OutputEditFieldLabel  matlab.ui.control.Label
        Input2EditField       matlab.ui.control.NumericEditField
        Input2EditFieldLabel  matlab.ui.control.Label
        Input1EditField       matlab.ui.control.NumericEditField
        Input1EditFieldLabel  matlab.ui.control.Label
        ButtonGroup           matlab.ui.container.ButtonGroup
        DivButton             matlab.ui.control.RadioButton
        MulButton             matlab.ui.control.RadioButton
        AddButton             matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
%             selectedButton = app.ButtonGroup.SelectedObject;

%             if app.AddButton.Value == 1
%                 app.OutputEditField.Value = app.Input1EditField.Value + app.Input2EditField.Value;
%             elseif app.MulButton.Value == 1
%                  app.OutputEditField.Value = app.Input1EditField.Value * app.Input2EditField.Value;
%             elseif app.DivButton.Value == 1
%                 app.OutputEditField.Value = app.Input1EditField.Value / app.Input2EditField.Value;
%             end
%             
            
        end

        % Button down function: ButtonGroup
        function ButtonGroupButtonDown(app, event)
%      
            if app.AddButton.Value == 1
                app.OutputEditField.Value = app.Input1EditField.Value + app.Input2EditField.Value;
            elseif app.MulButton.Value == 1
                 app.OutputEditField.Value = app.Input1EditField.Value * app.Input2EditField.Value;
            elseif app.DivButton.Value == 1
                app.OutputEditField.Value = app.Input1EditField.Value / app.Input2EditField.Value;
            end


%             switch app.ButtonGroup.SelectedObject
%                 case 'Add'
%                     app.OutputEditField.Value = app.Input1EditField.Value + app.Input2EditField.Value;
%                 case 'Mul'
%                     app.OutputEditField.Value = app.Input1EditField.Value * app.Input2EditField.Value;
%                 case 'Div'
%                     app.OutputEditField.Value = app.Input1EditField.Value / app.Input2EditField.Value;
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.ButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ButtonGroupButtonDown, true);
            app.AddButton = uiradiobutton(app.ButtonGroup);
            app.AddButton.Text = 'Add';
            app.AddButton.Value = true;
            app.MulButton = uiradiobutton(app.ButtonGroup);
            app.MulButton.Text = 'Mul';
            app.DivButton = uiradiobutton(app.ButtonGroup);
            app.DivButton.Text = 'Div';
            app.Input1EditFieldLabel = uilabel(app.UIFigure);
            app.Input1EditFieldLabel.Text = 'Input1';
            app.Input1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Input2EditFieldLabel = uilabel(app.UIFigure);
            app.Input2EditFieldLabel.Text = 'Input2';
            app.Input2EditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputEditFieldLabel = uilabel(app.UIFigure);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ButtonDown_Example_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end