classdef Switch_app_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Switch4       matlab.ui.control.ToggleSwitch
        Switch4Label  matlab.ui.control.Label
        Switch3       matlab.ui.control.RockerSwitch
        Switch3Label  matlab.ui.control.Label
        Switch2       matlab.ui.control.Switch
        Switch2Label  matlab.ui.control.Label
        Lamp          matlab.ui.control.Lamp
        LampLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            value = app.Switch2.Value;
            if strcmp(value,'On')
                app.Lamp.Color = 'y';
            else 
                app.Lamp.Color = 'm';
            end
            
        end

        % Value changed function: Switch3
        function Switch3ValueChanged(app, event)
            value = app.Switch3.Value;
            if strcmp(value,'On')
                app.Lamp.Color = 'c';
            else 
                app.Lamp.Color = 'k';
            end
        end

        % Value changed function: Switch4
        function Switch4ValueChanged(app, event)
            value = app.Switch4.Value;
            if strcmp(value,'On')
                app.Lamp.Color = 'r';
            else 
                app.Lamp.Color = 'g';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Switch2Label = uilabel(app.UIFigure);
            app.Switch2Label.Text = 'Switch2';
            app.Switch2 = uiswitch(app.UIFigure, 'slider');
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch3Label = uilabel(app.UIFigure);
            app.Switch3Label.Text = 'Switch3';
            app.Switch3 = uiswitch(app.UIFigure, 'rocker');
            app.Switch3.ValueChangedFcn = createCallbackFcn(app, @Switch3ValueChanged, true);
            app.Switch4Label = uilabel(app.UIFigure);
            app.Switch4Label.Text = 'Switch4';
            app.Switch4 = uiswitch(app.UIFigure, 'toggle');
            app.Switch4.ValueChangedFcn = createCallbackFcn(app, @Switch4ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Switch_app_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end