classdef Listbox_app_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        EditField3       matlab.ui.control.NumericEditField
        EditField3Label  matlab.ui.control.Label
        EditField2       matlab.ui.control.NumericEditField
        EditField2Label  matlab.ui.control.Label
        EditField        matlab.ui.control.NumericEditField
        EditFieldLabel   matlab.ui.control.Label
        ListBox          matlab.ui.control.ListBox
        ListBoxLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Double-clicked callback: ListBox
        function ListBoxDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            if app.ListBox.Value == 'Add'
            app.EditField3.Value = app.EditField.Value + app.EditField2.Value;
            elseif app.ListBox.Value == 'Mul'
                app.EditField3.Value = app.EditField.Value * app.EditField2.Value;
                elseif app.ListBox.Value == 'Sub'
                app.EditField3.Value = app.EditField.Value - app.EditField2.Value;
                elseif app.ListBox.Value == 'Div'
                app.EditField3.Value = app.EditField.Value / app.EditField2.Value;
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Add', 'Mul', 'Sub', 'Div'};
            app.ListBox.DoubleClickedFcn = createCallbackFcn(app, @ListBoxDoubleClicked, true);
            app.ListBox.Value = 'Add';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Listbox_app_Final

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end