classdef sqrt_cube_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        EditField2       matlab.ui.control.NumericEditField
        EditField2Label  matlab.ui.control.Label
        EditField        matlab.ui.control.NumericEditField
        EditFieldLabel   matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
        DropDownLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DropDownLabel.Text = 'Choices';
            app.EditFieldLabel.Text = 'Number';
            app.EditField2Label.Text = 'OutPut';
            app.DropDown.Items = {'Sqre','Cube', 'Quad', 'pent'};
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
%             switch app.DropDown.Value
%                 case 'Sqr'
%                     app.EditField2.Value = app.EditField.Value * app.EditField.Value;
%                 case 'Cube'
%                     app.EditField2.Value = app.EditField.Value * app.EditField.Value * app.EditField.Value;
%                     case 'Quad'
%                     app.EditField2.Value = app.EditField.Value * app.EditField.Value * app.EditField.Value * app.EditField.Value;
%             end

            if app.DropDown.Value == 'Sqre'
                app.EditField2.Value = app.EditField.Value * app.EditField.Value;
            elseif  app.DropDown.Value == 'Cube'
                app.EditField2.Value = app.EditField.Value * app.EditField.Value * app.EditField.Value;
        elseif  app.DropDown.Value == 'Quad'
            app.EditField2.Value = app.EditField.Value * app.EditField.Value * app.EditField.Value * app.EditField.Value;
            end
%         
            
        end

        % Clicked callback: DropDown
        function DropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ClickedFcn = createCallbackFcn(app, @DropDownClicked, true);
            app.DropDown.Value = 'Option 2';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sqrt_cube_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end